classdef Mav_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StartoptimizationButton     matlab.ui.control.Button
        anglesthatthearmformintheverticalplaneCheckBox  matlab.ui.control.CheckBox
        anglesthatthearmforminthehorizontalplaneCheckBox  matlab.ui.control.CheckBox
        LarmlengthCheckBox          matlab.ui.control.CheckBox
        numberofrotorsCheckBox      matlab.ui.control.CheckBox
        ParameterstooptimizeLabel   matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        Lamp2                       matlab.ui.control.Lamp
        Lamp3                       matlab.ui.control.Lamp
        Lamp4                       matlab.ui.control.Lamp
        NumberofrotorsSpinnerLabel  matlab.ui.control.Label
        NumberofrotorsSpinner       matlab.ui.control.Spinner
        SpinnerLabel                matlab.ui.control.Label
        Spinner                     matlab.ui.control.Spinner
        Label                       matlab.ui.control.Label
        Spinner_2                   matlab.ui.control.Spinner
        Label_2                     matlab.ui.control.Label
        Spinner_3                   matlab.ui.control.Spinner
        Label_3                     matlab.ui.control.Label
        Spinner_4                   matlab.ui.control.Spinner
        Label_4                     matlab.ui.control.Label
        Spinner_5                   matlab.ui.control.Spinner
        Label_5                     matlab.ui.control.Label
        Spinner_6                   matlab.ui.control.Spinner
        Label_6                     matlab.ui.control.Label
        Spinner_7                   matlab.ui.control.Spinner
        Label_7                     matlab.ui.control.Label
        Spinner_8                   matlab.ui.control.Spinner
        Label_8                     matlab.ui.control.Label
        Spinner_9                   matlab.ui.control.Spinner
        Label_9                     matlab.ui.control.Label
        Spinner_10                  matlab.ui.control.Spinner
        Label_10                    matlab.ui.control.Label
        Spinner_11                  matlab.ui.control.Spinner
        Label_11                    matlab.ui.control.Label
        Spinner_12                  matlab.ui.control.Spinner
        Label_12                    matlab.ui.control.Label
        Spinner_13                  matlab.ui.control.Spinner
        Label_13                    matlab.ui.control.Label
        Spinner_14                  matlab.ui.control.Spinner
        Label_14                    matlab.ui.control.Label
        Spinner_15                  matlab.ui.control.Spinner
        Label_15                    matlab.ui.control.Label
        Spinner_16                  matlab.ui.control.Spinner
        L0SpinnerLabel              matlab.ui.control.Label
        L0Spinner                   matlab.ui.control.Spinner
        InitialsolutionLabel        matlab.ui.control.Label
        ParametersoftheoptimizationfunctionLabel  matlab.ui.control.Label
        TooptimizethenumberofrotorstheoptimisationtoolLabel  matlab.ui.control.Label
        Spinner_17                  matlab.ui.control.Spinner
        Spinner_18                  matlab.ui.control.Spinner
        MinimumnumberofrotorsLabel  matlab.ui.control.Label
        MaximumnumberofrotorsLabel  matlab.ui.control.Label
        AlgorithmListBoxLabel       matlab.ui.control.Label
        AlgorithmListBox            matlab.ui.control.ListBox
        DisplayLabel                matlab.ui.control.Label
        DisplayListBox              matlab.ui.control.ListBox
        MaximumiterationsofthealgorithmLabel  matlab.ui.control.Label
        MaximumiterationsofthealgorithmSpinner  matlab.ui.control.Spinner
        ToleranceontheconstraintviolationSpinnerLabel  matlab.ui.control.Label
        ToleranceontheconstraintviolationSpinner  matlab.ui.control.Spinner
        TerminationtoleranceontheoptargSpinnerLabel  matlab.ui.control.Label
        TerminationtoleranceontheoptargSpinner  matlab.ui.control.Spinner
        ParametersoftheoptimizationfunctionLabel_2  matlab.ui.control.Label
        Spinner_19Label             matlab.ui.control.Label
        Spinner_19                  matlab.ui.control.Spinner
        MaximalnumberoftimesfmincomisiteratedSpinnerLabel  matlab.ui.control.Label
        MaximalnumberoftimesfmincomisiteratedSpinner  matlab.ui.control.Spinner
        OptimizetiltinganglesforeveryiterationCheckBox  matlab.ui.control.CheckBox
        OptimizationtooltofindanoptimaldronedesignLabel  matlab.ui.control.Label
        CostfunctionLabel           matlab.ui.control.Label
        CostfunctionListBox         matlab.ui.control.ListBox
    end


    properties (Access = private)
        Property % Description
        Optimize_theta = false
        Optimize_L = false
        Optimize_n = false
        n = 3;
    end


    methods (Access = private)

        % Button pushed function: StartoptimizationButton
        function StartoptimizationButtonPushed(app, event)
            %Mav_optimize_beta_theta_main();
        end

        % Callback function
        function ParameterstoOptimizeListBoxValueChanged(app, event)
            value = app.ParameterstoOptimizeListBox.Value;
        end

        % Value changed function: 
        % anglesthatthearmformintheverticalplaneCheckBox
        function anglesthatthearmformintheverticalplaneCheckBoxValueChanged(app, event)

        end

        % Value changed function: 
        % anglesthatthearmforminthehorizontalplaneCheckBox
        function anglesthatthearmforminthehorizontalplaneCheckBoxValueChanged(app, event)
            value = app.anglesthatthearmforminthehorizontalplaneCheckBox.Value;
            if value == true
                app.Optimize_theta = true;
                app.Lamp.Color = 'green';
                app.Spinner_9.Enable = 'on';
                app.Spinner_10.Enable = 'on';
                app.Spinner_11.Enable = 'on';
                if app.n == 4
                    app.Spinner_12.Enable = 'on';
                elseif app.n == 5
                    app.Spinner_12.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                elseif app.n == 6
                    app.Spinner_12.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                elseif app.n == 7
                    app.Spinner_12.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                    app.Spinner_15.Enable = 'on';
                elseif app.n == 8
                    app.Spinner_12.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                    app.Spinner_15.Enable = 'on';
                    app.Spinner_16.Enable = 'on';
                end
            else
                app.Optimize_theta = false;
                app.Lamp.Color = 'red';
                app.Spinner_9.Enable = 'off';
                app.Spinner_10.Enable = 'off';
                app.Spinner_11.Enable = 'off';
                if app.n == 4
                    app.Spinner_12.Enable = 'off';
                elseif app.n == 5
                    app.Spinner_12.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                elseif app.n == 6
                    app.Spinner_12.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                    app.Spinner_14.Enable = 'off';
                    app.Spinner_14.Enable = 'off';
                elseif app.n == 7
                    app.Spinner_12.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                    app.Spinner_14.Enable = 'off';
                    app.Spinner_15.Enable = 'off';
                elseif app.n == 8
                    app.Spinner_12.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                    app.Spinner_14.Enable = 'off';
                    app.Spinner_15.Enable = 'off';
                    app.Spinner_16.Enable = 'off';
                end
            end
            if app.Optimize_n == true
                app.Spinner_9.Enable = 'off';
                app.Spinner_10.Enable = 'off';
                app.Spinner_11.Enable = 'off';
                app.Spinner_12.Enable = 'off';
                app.Spinner_13.Enable = 'off';
                app.Spinner_14.Enable = 'off';
                app.Spinner_15.Enable = 'off';
                app.Spinner_16.Enable = 'off';
            end
        end

        % Value changed function: LarmlengthCheckBox
        function LarmlengthCheckBoxValueChanged(app, event)
            value = app.LarmlengthCheckBox.Value;
            if value == true
                app.Optimize_L = true;
                app.Lamp2.Color = 'green';
                app.L0Spinner.Enable = 'on';
            else
                app.Optimize_L = false;
                app.Lamp2.Color = 'red';
                app.L0Spinner.Enable = 'off';
            end
            if app.Optimize_n == true
                app.L0Spinner.Enable = 'off';
            end
                
        end

        % Value changed function: numberofrotorsCheckBox
        function numberofrotorsCheckBoxValueChanged(app, event)
            value = app.numberofrotorsCheckBox.Value;
            if value == true
                app.TooptimizethenumberofrotorstheoptimisationtoolLabel.Visible = 'on';
                app.MinimumnumberofrotorsLabel.Visible = 'on';
                app.MaximumnumberofrotorsLabel.Visible = 'on';
                app.Spinner_17.Visible = 'on';
                app.Spinner_18.Visible = 'on';
                app.Optimize_n = true;
                app.Lamp3.Color = 'green';
                app.NumberofrotorsSpinner.Enable = 'off';
                app.L0Spinner.Enable = 'off';
                app.Spinner.Enable = 'off';
                app.Spinner_2.Enable = 'off';
                app.Spinner_3.Enable = 'off';
                app.Spinner_4.Enable = 'off';
                app.Spinner_5.Enable = 'off';
                app.Spinner_6.Enable = 'off';
                app.Spinner_7.Enable = 'off';
                app.Spinner_8.Enable = 'off';
                app.Spinner_9.Enable = 'off';
                app.Spinner_10.Enable = 'off';
                app.Spinner_11.Enable = 'off';
                app.Spinner_12.Enable = 'off';
                app.Spinner_13.Enable = 'off';
                app.Spinner_14.Enable = 'off';
                app.Spinner_15.Enable = 'off';
                app.Spinner_16.Enable = 'off';
            else
                app.TooptimizethenumberofrotorstheoptimisationtoolLabel.Visible = 'off';
                app.MinimumnumberofrotorsLabel.Visible = 'off';
                app.MaximumnumberofrotorsLabel.Visible = 'off';
                app.Spinner_17.Visible = 'off';
                app.Spinner_18.Visible = 'off';
                app.Optimize_n = false;
                if app.Optimize_L == true
                    app.L0Spinner.Enable = 'on';
                else
                    app.L0Spinner.Enable = 'off';
                end
                app.Lamp3.Color = 'red';
                app.NumberofrotorsSpinner.Enable = 'on';
                app.Spinner.Enable = 'on';
                app.Spinner_2.Enable = 'on';
                app.Spinner_3.Enable = 'on';
                app.Spinner_4.Enable = 'off';
                app.Spinner_5.Enable = 'off';
                app.Spinner_6.Enable = 'off';
                app.Spinner_7.Enable = 'off';
                app.Spinner_8.Enable = 'off';
                if app.Optimize_theta == true
                    app.Spinner_9.Enable = 'on';
                    app.Spinner_10.Enable = 'on';
                    app.Spinner_11.Enable = 'on';
                    app.Spinner_12.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                    app.Spinner_14.Enable = 'off';
                    app.Spinner_15.Enable = 'off';
                    app.Spinner_16.Enable = 'off';
                else
                    app.Spinner_9.Enable = 'off';
                    app.Spinner_10.Enable = 'off';
                    app.Spinner_11.Enable = 'off';
                    app.Spinner_12.Enable = 'off';
                    app.Spinner_13.Enable = 'off';
                    app.Spinner_14.Enable = 'off';
                    app.Spinner_15.Enable = 'off';
                    app.Spinner_16.Enable = 'off';
                end
                if app.n ==4
                    app.Spinner_4.Enable = 'on';
                    if app.Optimize_theta == true
                        app.Spinner_12.Enable = 'on';
                    end
                elseif app.n ==5
                    app.Spinner_4.Enable = 'on';
                    app.Spinner_5.Enable = 'on';
                    if app.Optimize_theta == true
                        app.Spinner_12.Enable = 'on';
                        app.Spinner_13.Enable = 'on';
                    end
                elseif app.n ==6
                    app.Spinner_4.Enable = 'on';
                    app.Spinner_5.Enable = 'on';
                    app.Spinner_6.Enable = 'on';
                    if app.Optimize_theta == true
                        app.Spinner_12.Enable = 'on';
                        app.Spinner_13.Enable = 'on';
                        app.Spinner_14.Enable = 'on';
                    end
                elseif app.n==7
                    app.Spinner_4.Enable = 'on';
                    app.Spinner_5.Enable = 'on';
                    app.Spinner_6.Enable = 'on';
                    app.Spinner_7.Enable = 'on';
                    if app.Optimize_theta == true
                        app.Spinner_12.Enable = 'on';
                        app.Spinner_13.Enable = 'on';
                        app.Spinner_14.Enable = 'on';
                        app.Spinner_15.Enable = 'on';
                    end
                elseif app.n==8
                    app.Spinner_4.Enable = 'on';
                    app.Spinner_5.Enable = 'on';
                    app.Spinner_6.Enable = 'on';
                    app.Spinner_7.Enable = 'on';
                    app.Spinner_8.Enable = 'on';
                    if app.Optimize_theta == true
                        app.Spinner_12.Enable = 'on';
                        app.Spinner_13.Enable = 'on';
                        app.Spinner_14.Enable = 'on';
                        app.Spinner_15.Enable = 'on';
                        app.Spinner_16.Enable = 'on';
                    end
                end
            end
        end

        % Callback function
        function NumberofrotorsSliderValueChanged(app, event)
            value = app.NumberofrotorsSlider.Value;
        end

        % Value changed function: NumberofrotorsSpinner
        function NumberofrotorsSpinnerValueChanged(app, event)
            value = app.NumberofrotorsSpinner.Value;
            app.n = value;
            app.Spinner.Enable = 'on';
            app.Spinner_2.Enable = 'on';
            app.Spinner_3.Enable = 'on';
            app.Spinner_4.Enable = 'off';
            app.Spinner_5.Enable = 'off';
            app.Spinner_6.Enable = 'off';
            app.Spinner_7.Enable = 'off';
            app.Spinner_8.Enable = 'off';
            if app.Optimize_theta == true
                app.Spinner_9.Enable = 'on';
                app.Spinner_10.Enable = 'on';
                app.Spinner_11.Enable = 'on';
                app.Spinner_12.Enable = 'off';
                app.Spinner_13.Enable = 'off';
                app.Spinner_14.Enable = 'off';
                app.Spinner_15.Enable = 'off';
                app.Spinner_16.Enable = 'off';
            else
                app.Spinner_9.Enable = 'off';
                app.Spinner_10.Enable = 'off';
                app.Spinner_11.Enable = 'off';
                app.Spinner_12.Enable = 'off';
                app.Spinner_13.Enable = 'off';
                app.Spinner_14.Enable = 'off';
                app.Spinner_15.Enable = 'off';
                app.Spinner_16.Enable = 'off';
            end
            if value ==4
                app.Spinner_4.Enable = 'on';
                if app.Optimize_theta == true
                    app.Spinner_12.Enable = 'on';
                end
            elseif value==5
                app.Spinner_4.Enable = 'on';
                app.Spinner_5.Enable = 'on';
                if app.Optimize_theta == true
                    app.Spinner_12.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                end
            elseif value==6
                app.Spinner_4.Enable = 'on';
                app.Spinner_5.Enable = 'on';
                app.Spinner_6.Enable = 'on';
                if app.Optimize_theta == true
                    app.Spinner_12.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                end
            elseif value==7
                app.Spinner_4.Enable = 'on';
                app.Spinner_5.Enable = 'on';
                app.Spinner_6.Enable = 'on';
                app.Spinner_7.Enable = 'on';
                if app.Optimize_theta == true
                    app.Spinner_12.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                    app.Spinner_15.Enable = 'on';
                end
            elseif value==8
                app.Spinner_4.Enable = 'on';
                app.Spinner_5.Enable = 'on';
                app.Spinner_6.Enable = 'on';
                app.Spinner_7.Enable = 'on';
                app.Spinner_8.Enable = 'on';
                if app.Optimize_theta == true
                    app.Spinner_12.Enable = 'on';
                    app.Spinner_13.Enable = 'on';
                    app.Spinner_14.Enable = 'on';
                    app.Spinner_15.Enable = 'on';
                    app.Spinner_16.Enable = 'on';
                end
            end
        end

        % Value changed function: Spinner_17
        function Spinner_17ValueChanged(app, event)
            value = app.Spinner_17.Value;
            app.Spinner_18.Limits = [value+1, 10];
            app.Spinner_18.Value = value+2;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartoptimizationButton = uibutton(app.UIFigure, 'push');
            app.StartoptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartoptimizationButtonPushed, true);
            app.StartoptimizationButton.Text = 'Start optimization';
            app.anglesthatthearmformintheverticalplaneCheckBox = uicheckbox(app.UIFigure);
            app.anglesthatthearmformintheverticalplaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @anglesthatthearmformintheverticalplaneCheckBoxValueChanged, true);
            app.anglesthatthearmformintheverticalplaneCheckBox.Enable = 'off';
            app.anglesthatthearmformintheverticalplaneCheckBox.Text = 'β (angles that the arm form in the vertical plane)';
            app.anglesthatthearmformintheverticalplaneCheckBox.Value = true;
            app.anglesthatthearmforminthehorizontalplaneCheckBox = uicheckbox(app.UIFigure);
            app.anglesthatthearmforminthehorizontalplaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @anglesthatthearmforminthehorizontalplaneCheckBoxValueChanged, true);
            app.anglesthatthearmforminthehorizontalplaneCheckBox.Text = 'θ (angles that the arm form in the horizontal plane)';
            app.LarmlengthCheckBox = uicheckbox(app.UIFigure);
            app.LarmlengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @LarmlengthCheckBoxValueChanged, true);
            app.LarmlengthCheckBox.Text = 'L (arm length)';
            app.numberofrotorsCheckBox = uicheckbox(app.UIFigure);
            app.numberofrotorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @numberofrotorsCheckBoxValueChanged, true);
            app.numberofrotorsCheckBox.Text = 'n (umber of rotors)';
            app.ParameterstooptimizeLabel = uilabel(app.UIFigure);
            app.ParameterstooptimizeLabel.Text = 'Parameters to optimize:';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp3 = uilamp(app.UIFigure);
            app.Lamp4 = uilamp(app.UIFigure);
            app.Lamp4.Enable = 'off';
            app.NumberofrotorsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofrotorsSpinnerLabel.Text = 'Number of rotors';
            app.NumberofrotorsSpinner = uispinner(app.UIFigure);
            app.NumberofrotorsSpinner.Limits = [3 8];
            app.NumberofrotorsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofrotorsSpinnerValueChanged, true);
            app.NumberofrotorsSpinner.Value = 3;
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'β1:';
            app.Spinner = uispinner(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'β2:';
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'β3:';
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'β4:';
            app.Spinner_4 = uispinner(app.UIFigure);
            app.Spinner_4.Enable = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'β5:';
            app.Spinner_5 = uispinner(app.UIFigure);
            app.Spinner_5.Enable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'β6:';
            app.Spinner_6 = uispinner(app.UIFigure);
            app.Spinner_6.Enable = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'β7:';
            app.Spinner_7 = uispinner(app.UIFigure);
            app.Spinner_7.Enable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'β8:';
            app.Spinner_8 = uispinner(app.UIFigure);
            app.Spinner_8.Enable = 'off';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'θ1:';
            app.Spinner_9 = uispinner(app.UIFigure);
            app.Spinner_9.Enable = 'off';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'θ2:';
            app.Spinner_10 = uispinner(app.UIFigure);
            app.Spinner_10.Enable = 'off';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'θ3:';
            app.Spinner_11 = uispinner(app.UIFigure);
            app.Spinner_11.Enable = 'off';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'θ4:';
            app.Spinner_12 = uispinner(app.UIFigure);
            app.Spinner_12.Enable = 'off';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = 'θ5:';
            app.Spinner_13 = uispinner(app.UIFigure);
            app.Spinner_13.Enable = 'off';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = 'θ6:';
            app.Spinner_14 = uispinner(app.UIFigure);
            app.Spinner_14.Enable = 'off';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = 'θ7:';
            app.Spinner_15 = uispinner(app.UIFigure);
            app.Spinner_15.Enable = 'off';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = 'θ8:';
            app.Spinner_16 = uispinner(app.UIFigure);
            app.Spinner_16.Enable = 'off';
            app.L0SpinnerLabel = uilabel(app.UIFigure);
            app.L0SpinnerLabel.Text = 'L0:';
            app.L0Spinner = uispinner(app.UIFigure);
            app.L0Spinner.Step = 0.05;
            app.L0Spinner.Limits = [0.05 0.5];
            app.L0Spinner.Enable = 'off';
            app.L0Spinner.Value = 0.3;
            app.InitialsolutionLabel = uilabel(app.UIFigure);
            app.InitialsolutionLabel.Text = 'Initial solution:';
            app.ParametersoftheoptimizationfunctionLabel = uilabel(app.UIFigure);
            app.ParametersoftheoptimizationfunctionLabel.Text = 'Parameters of the optimization function:';
            app.TooptimizethenumberofrotorstheoptimisationtoolLabel = uilabel(app.UIFigure);
            app.TooptimizethenumberofrotorstheoptimisationtoolLabel.Visible = 'off';
            app.TooptimizethenumberofrotorstheoptimisationtoolLabel.Text = 'To optimize the number of rotors, the optimization tool uses a genetic algorithm, therefore there is no need for an initial solution';
            app.Spinner_17 = uispinner(app.UIFigure);
            app.Spinner_17.Limits = [3 8];
            app.Spinner_17.ValueChangedFcn = createCallbackFcn(app, @Spinner_17ValueChanged, true);
            app.Spinner_17.Visible = 'off';
            app.Spinner_17.Value = 3;
            app.Spinner_18 = uispinner(app.UIFigure);
            app.Spinner_18.Limits = [4 10];
            app.Spinner_18.Visible = 'off';
            app.Spinner_18.Value = 5;
            app.MinimumnumberofrotorsLabel = uilabel(app.UIFigure);
            app.MinimumnumberofrotorsLabel.Visible = 'off';
            app.MinimumnumberofrotorsLabel.Text = 'Minimum number of rotors:';
            app.MaximumnumberofrotorsLabel = uilabel(app.UIFigure);
            app.MaximumnumberofrotorsLabel.Visible = 'off';
            app.MaximumnumberofrotorsLabel.Text = 'Maximum number of rotors:';
            app.AlgorithmListBoxLabel = uilabel(app.UIFigure);
            app.AlgorithmListBoxLabel.Text = 'Algorithm:';
            app.AlgorithmListBox = uilistbox(app.UIFigure);
            app.AlgorithmListBox.Items = {'sqp', 'sqp-legacy', 'interior-point', 'active-set'};
            app.AlgorithmListBox.Value = 'sqp';
            app.DisplayLabel = uilabel(app.UIFigure);
            app.DisplayLabel.Text = 'Display:';
            app.DisplayListBox = uilistbox(app.UIFigure);
            app.DisplayListBox.Items = {'displays no output', 'displays output at each iteration', 'displays output only if the function does not converge', 'displays only the final output,'};
            app.DisplayListBox.Value = 'displays no output';
            app.MaximumiterationsofthealgorithmLabel = uilabel(app.UIFigure);
            app.MaximumiterationsofthealgorithmLabel.Text = 'Maximum iterations of the algorithm:';
            app.MaximumiterationsofthealgorithmSpinner = uispinner(app.UIFigure);
            app.MaximumiterationsofthealgorithmSpinner.Limits = [1000 100000];
            app.MaximumiterationsofthealgorithmSpinner.Value = 10000;
            app.ToleranceontheconstraintviolationSpinnerLabel = uilabel(app.UIFigure);
            app.ToleranceontheconstraintviolationSpinnerLabel.Text = 'Tolerance on the constraint violation:';
            app.ToleranceontheconstraintviolationSpinner = uispinner(app.UIFigure);
            app.ToleranceontheconstraintviolationSpinner.Step = 1e-07;
            app.ToleranceontheconstraintviolationSpinner.Limits = [1e-09 0.0001];
            app.ToleranceontheconstraintviolationSpinner.Value = 1e-06;
            app.TerminationtoleranceontheoptargSpinnerLabel = uilabel(app.UIFigure);
            app.TerminationtoleranceontheoptargSpinnerLabel.Text = 'Termination tolerance on the opt. arg.:';
            app.TerminationtoleranceontheoptargSpinner = uispinner(app.UIFigure);
            app.TerminationtoleranceontheoptargSpinner.Step = 1e-07;
            app.TerminationtoleranceontheoptargSpinner.Limits = [1e-10 0.0001];
            app.TerminationtoleranceontheoptargSpinner.Value = 1e-06;
            app.ParametersoftheoptimizationfunctionLabel_2 = uilabel(app.UIFigure);
            app.ParametersoftheoptimizationfunctionLabel_2.Text = 'Parameters for the optimization of tilting angles (alpha) and rotor speeds (w):';
            app.Spinner_19Label = uilabel(app.UIFigure);
            app.Spinner_19Label.Text = 'step to define the number of directions in which to compute force and torque';
            app.Spinner_19 = uispinner(app.UIFigure);
            app.Spinner_19.Step = 0.05;
            app.Spinner_19.Limits = [0.05 1];
            app.Spinner_19.Value = 0.1;
            app.MaximalnumberoftimesfmincomisiteratedSpinnerLabel = uilabel(app.UIFigure);
            app.MaximalnumberoftimesfmincomisiteratedSpinnerLabel.Text = 'Maximal number of times fmincom is iterated to find the optimal solution';
            app.MaximalnumberoftimesfmincomisiteratedSpinner = uispinner(app.UIFigure);
            app.MaximalnumberoftimesfmincomisiteratedSpinner.Limits = [50 200];
            app.MaximalnumberoftimesfmincomisiteratedSpinner.Value = 150;
            app.OptimizetiltinganglesforeveryiterationCheckBox = uicheckbox(app.UIFigure);
            app.OptimizetiltinganglesforeveryiterationCheckBox.Text = 'Optimize tilting angles for every iteration';
            app.OptimizationtooltofindanoptimaldronedesignLabel = uilabel(app.UIFigure);
            app.OptimizationtooltofindanoptimaldronedesignLabel.Text = 'Optimization tool to find an optimal drone design:';
            app.CostfunctionLabel = uilabel(app.UIFigure);
            app.CostfunctionLabel.Text = 'Cost function: ';
            app.CostfunctionListBox = uilistbox(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Mav_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end